classdef sigPreProcessAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SelectFolderButton           matlab.ui.control.Button
        PathEditField                matlab.ui.control.EditField
        PathEditFieldLabel           matlab.ui.control.Label
        StatusTextArea               matlab.ui.control.TextArea
        StatusTextAreaLabel          matlab.ui.control.Label
        httpsgithubcomcincibrainlabsigPreProcessAnalyzerLabel  matlab.ui.control.Label
        EEGPreprocessingAnalyzerv1Label  matlab.ui.control.Label
        FeatureGenerationPanel       matlab.ui.container.Panel
        PostProcessingPanel          matlab.ui.container.Panel
        PreProcessingPanel           matlab.ui.container.Panel
        FilesPanel                   matlab.ui.container.Panel
        EEGSETStructureDetailsLabel  matlab.ui.control.Label
        Tree                         matlab.ui.container.Tree
        Node                         matlab.ui.container.TreeNode
        Node2                        matlab.ui.container.TreeNode
        Node3                        matlab.ui.container.TreeNode
        Node4                        matlab.ui.container.TreeNode
        SelectapathwithSETEEGfilesLabel  matlab.ui.control.Label
        FilesListBox                 matlab.ui.control.ListBox
        FilesListBoxLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        spac % reserved for controller class
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.spac = spaController();
            app.StatusTextArea.Value = sprintf("%s", app.StatusTextArea.Value{1}, "Created Controller Class");
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            app.PathEditField.Value = app.spac.openFolder;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FilesPanel = uipanel(app.UIFigure);
            app.FilesPanel.Title = 'Files';
            app.FilesListBoxLabel = uilabel(app.FilesPanel);
            app.FilesListBoxLabel.Text = 'Files:';
            app.FilesListBox = uilistbox(app.FilesPanel);
            app.SelectapathwithSETEEGfilesLabel = uilabel(app.FilesPanel);
            app.SelectapathwithSETEEGfilesLabel.Text = 'Select a path with SET EEG files:';
            app.Tree = uitree(app.FilesPanel);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.EEGSETStructureDetailsLabel = uilabel(app.FilesPanel);
            app.EEGSETStructureDetailsLabel.Text = 'EEG SET Structure Details:';
            app.PreProcessingPanel = uipanel(app.UIFigure);
            app.PreProcessingPanel.Title = 'PreProcessing';
            app.PostProcessingPanel = uipanel(app.UIFigure);
            app.PostProcessingPanel.Title = 'PostProcessing';
            app.FeatureGenerationPanel = uipanel(app.UIFigure);
            app.FeatureGenerationPanel.Title = 'Feature Generation';
            app.EEGPreprocessingAnalyzerv1Label = uilabel(app.UIFigure);
            app.EEGPreprocessingAnalyzerv1Label.Text = 'EEG Preprocessing Analyzer v.1';
            app.httpsgithubcomcincibrainlabsigPreProcessAnalyzerLabel = uilabel(app.UIFigure);
            app.httpsgithubcomcincibrainlabsigPreProcessAnalyzerLabel.Interpreter = 'html';
            app.httpsgithubcomcincibrainlabsigPreProcessAnalyzerLabel.Text = '<a href=https://github.com/cincibrainlab/sigPreProcessAnalyzer>https://github.com/cincibrainlab/sigPreProcessAnalyzer</a>';
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status:';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.PathEditFieldLabel = uilabel(app.UIFigure);
            app.PathEditFieldLabel.Text = 'Path:';
            app.PathEditField = uieditfield(app.UIFigure, 'text');
            app.SelectFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sigPreProcessAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end